classdef Proj1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextinfileorwriteitEditField   matlab.ui.control.EditField
        TextinfileorwriteitEditFieldLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        RelativeEntropyEditField       matlab.ui.control.EditField
        RelativeEntropyEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        jcEntropyTable                 matlab.ui.control.Table
        EntropyEditField               matlab.ui.control.EditField
        ExitButton                     matlab.ui.control.Button
        EntropyEditFieldLabel          matlab.ui.control.Label
        SelectButton                   matlab.ui.control.Button
        fileSel                        matlab.ui.control.EditField
        EnterthepathofatextfileEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        originalText = ""; % This keeps hold of the file we select
        decodedText = ""; % The file we receive from the BSC_p after decoding.
        % Bits sent and received over the BSC_p
        fileSentBits = "";
        fileReceivedBits = "";
        channelError = 0.05;
        charList = char(strcat(char(65:90),char(97:122), char(49:57), ",. "));
        % charList contains all the possible chars we have in any given
        % sample test case. we can quickly find the binary code for a
        % charachter by finding its index in the array then converting it
        % into binary.

    end

    methods (Access = private)

        function y = sendOverBSCp(app, bit)
            % Returns the reuslt "y" of a bit sent over a binary symmetric
            % channel with error probability = p.
            p = app.channelError;
            random = rand(); % Returns a value between 0 & 1 by default.
            if (random > p)
                % The random value is larger than the probability of error,
                % We send back the bit unchanged.
                if(bit == '1')
                    y = true;
                else
                    y = false;
                end
                return
            else
                % Random value is less than p.
                % make the error.
                if(bit == '0')
                    y = true;
                else
                    y = false;
                end
            end


        end

        function binaryCode = getLetterCode(app, char)
            % Find the index of the given charachter in our charList,
            % then convert the index to binary.
            charIDX = strfind(app.charList,char);

            binaryCode = dec2bin(charIDX - 1,6);
            % Minus one because MATLAB, the best programming language ever
            % made, starts its indicies from ONE, not ZERO.
        end

        function charDecoded = decodeChar(app, bitStream)
            idx = bin2dec(bitStream)+1;
            charDecoded = app.charList(idx);
        end

        function fileDecoded = decodeFile(app, bitStream)
            idx = 1;
            for i = 1:6:length(bitStream)-5
                fileDecoded(idx) = app.decodeChar(char(extractBetween(bitStream, i, i+5)));
                idx = idx + 1;
            end
        end

        function pmfCharArr = PMF(app, text)
            % Returns the probability of each charachter in a neat array
            textLen = length(text);

            % For each charachter in our charList, count how many times
            % the char ocuured.
            pmfCharArr = zeros(1, length(app.charList));
            for idx = 1:length(app.charList)
                currentChar = app.charList(idx);
                pmfCharArr(idx) = sum(text == currentChar) / textLen;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.jcEntropyTable.Data = {'-', '-', '-'};
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file , path] = uigetfile ('*.txt') ;
            app.originalText = fileread (strcat(path,file));
            app.fileSel.Value = strcat(path,file) ;
            app.TextinfileorwriteitEditField.Value = app.originalText;

        end

        % Value changed function: fileSel
        function fileSelValueChanged(app, event)
            value = app.fileSel.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % plot(app.UIAxes,pdf(temp,[0 10]));

        end

        % Value changed function: EntropyEditField
        function EntropyEditFieldValueChanged(app, event)
            value = app.EntropyEditField.Value;

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function getYPushed(app, event)
            % Preprocess the input file (remove all bad charachters)
            app.originalText(~ismember(app.originalText, app.charList)) = '';

            % Convert each char of our file into it's 6-bit code.
            app.fileSentBits = cell2mat(arrayfun(@app.getLetterCode,app.originalText, 'UniformOutput', false));

            % Send the file on the binary symmetric channel

            zeroASCII = 48;
            bitStreamLogicalArray = arrayfun(@app.sendOverBSCp, app.fileSentBits);
            app.fileReceivedBits = char(double(bitStreamLogicalArray)+zeroASCII);
            app.decodedText = app.decodeFile(app.fileReceivedBits);

            % Each row in this matrix is an (X,Y) pair.
            xyTable = [transpose(app.originalText) transpose(app.decodedText)];

            px = app.PMF(app.originalText);
            py = app.PMF(app.decodedText);

            % We now make the joint probability table.
            jointProb = zeros(64);

            % We need to calculate how many times each (X,Y) pair occurs
            % then divide that by the number of pairs in the system (number
            % of chars sent).

            pairNum = length(app.originalText);

            % Whenever we find a pair, add 1 to its cell in the table,
            % then finally we divide the whole matrix by pairNum.
            % This should do the job in O(n) (I think)

            for idx = 1:size(xyTable,1)
                pair = xyTable(idx,:);

                x = pair(1);
                y = pair(2);

                xIndex = strfind(app.charList, x);
                yIndex = strfind(app.charList, y);
                jointProb(xIndex, yIndex) = jointProb(xIndex, yIndex)+1;
            end

            jointProb = jointProb ./ pairNum;


            xEntropy = -sum(px(find(px)).*log2(px(find(px))));
            yEntropy = -sum(py(find(py)).*log2(py(find(py))));

            % We now need to calculate the joint entropy
            % H(X,Y) = -1 * sum (P(X,Y) * log2(P(X,Y)))
            % X => Sent chars | Y => Received Chars

            jointProbNonZero = jointProb(find(jointProb));

            jointEntropy = -1 * sum(jointProbNonZero .* log2(jointProbNonZero));


            % Conditional Entropy:
            % H(Y|X) = -1 * sum(P(X,Y) * P(Y|X))
            % We can get P(Y|X) from the formula: P(Y|X) = P(X,Y)/P(X)
            pYcondX = bsxfun(@rdivide,jointProb,px(:));
            % This magical function divides each row by a correspoding
            % element in a vector.Wow.

            % Remove NaN and zeros
            nonZeroPyCondX = rmmissing(pYcondX(find(pYcondX)));

            % Calculate conditional entropy H(Y|X)
            conditionalEntropy = -sum(jointProbNonZero .* log2(nonZeroPyCondX));

            % Now we verify that H(X,Y) = H(X) + H(Y|X).
            % This is the same as verifying H(Y|X) = H(X,Y) - H(X)

            testConditionalEntropy = jointEntropy - xEntropy

            % Display Data
            app.jcEntropyTable.Data = {jointEntropy, conditionalEntropy, testConditionalEntropy};

        end

        % Value changed function: RelativeEntropyEditField
        function RelativeEntropyEditFieldValueChanged(app, event)
            value = app.RelativeEntropyEditField.Value;

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Preprocess the input file (remove all bad charachters)
            app.originalText(~ismember(app.originalText, app.charList)) = '';

            % Convert each char of our file into it's 6-bit code.
            app.fileSentBits = cell2mat(arrayfun(@app.getLetterCode,app.originalText, 'UniformOutput', false));

            % Send the file on the binary symmetric channel

            zeroASCII = 48;
            bitStreamLogicalArray = arrayfun(@app.sendOverBSCp, app.fileSentBits);
            app.fileReceivedBits = char(double(bitStreamLogicalArray)+zeroASCII);
            app.decodedText = app.decodeFile(app.fileReceivedBits);

            % Each row in this matrix is an (X,Y) pair.
            xyTable = [transpose(app.originalText) transpose(app.decodedText)];

            px = app.PMF(app.originalText);
            py = app.PMF(app.decodedText);

            % We now make the joint probability table.
            jointProb = zeros(64);

            % We need to calculate how many times each (X,Y) pair occurs
            % then divide that by the number of pairs in the system (number
            % of chars sent).

            pairNum = length(app.originalText);

            % Whenever we find a pair, add 1 to its cell in the table,
            % then finally we divide the whole matrix by pairNum.
            % This should do the job in O(n) (I think)

            for idx = 1:size(xyTable,1)
                pair = xyTable(idx,:);

                x = pair(1);
                y = pair(2);

                xIndex = strfind(app.charList, x);
                yIndex = strfind(app.charList, y);
                jointProb(xIndex, yIndex) = jointProb(xIndex, yIndex)+1;
            end

            jointProb = jointProb ./ pairNum;


            xEntropy = -sum(px(find(px)).*log2(px(find(px))));
            yEntropy = -sum(py(find(py)).*log2(py(find(py))));

            % We now need to calculate the joint entropy
            % H(X,Y) = -1 * sum (P(X,Y) * log2(P(X,Y)))
            % X => Sent chars | Y => Received Chars

            jointProbNonZero = jointProb(find(jointProb));

            jointEntropy = -1 * sum(jointProbNonZero .* log2(jointProbNonZero));


            % Conditional Entropy:
            % H(Y|X) = -1 * sum(P(X,Y) * P(Y|X))
            % We can get P(Y|X) from the formula: P(Y|X) = P(X,Y)/P(X)
            pYcondX = bsxfun(@rdivide,jointProb,px(:));
            % This magical function divides each row by a correspoding
            % element in a vector.Wow.

            % Remove NaN and zeros
            nonZeroPyCondX = rmmissing(pYcondX(find(pYcondX)));

            % Calculate conditional entropy H(Y|X)
            conditionalEntropy = -sum(jointProbNonZero .* log2(nonZeroPyCondX));

            % Now we verify that H(X,Y) = H(X) + H(Y|X).
            % This is the same as verifying H(Y|X) = H(X,Y) - H(X)

            testConditionalEntropy = jointEntropy - xEntropy

            % Display Data
            app.jcEntropyTable.Data = {jointEntropy, conditionalEntropy, testConditionalEntropy};
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            text = app.TextinfileorwriteitEditField.Value;

            if (ischar(text)==1)
                l=length(text);
                uniqueChars = unique(text);   % String temp has all unique characters sorted
                lenChar=length(uniqueChars);
                q = 1/lenChar ;          % relative entropy " Uniform "

                %ProbMassFunc = app.PMF(text); % declare the Probability Mass Function with size of special chars
                %PMF%
                f=zeros(1,lenChar);
                for i=1:lenChar
                    % Count the occurence of unique characters
                    f(i)=length(findstr(text,uniqueChars(i)));
                end

                p=zeros(1,lenChar);
                PMF = [length(lenChar)]; % declare the Probability Mass Function with size of special chars
                for i=1:lenChar
                    %  Probabilities for each unique character
                    p(i)=f(i)/l;
                    PMF(i) = p(i);  %PMF

                end
                %PMF%

                %Entropy & Relative Entropy%
                H=0;
                R=0;

                for  i=1:lenChar
                    % Calculating the Entropy
                    H = H + (-1*PMF(i)*log2(PMF(i)));

                    % Calculating the relative Entropy
                    R = R + (PMF(i)*log2(PMF(i)/q));
                end
                %Entropy & Relative Entropy%

                %Print%
                %Print PMF%

                inc = 0
                for  i=1:lenChar
                    inc = inc + 1;
                    t={PMF(i)  uniqueChars(i) inc};
                    app.UITable.Data  = [app.UITable.Data ; t];
                end
                %Print Relative Entropy%
                app.RelativeEntropyEditField.Value = num2str(R);

                %print entropy%
                app.EntropyEditField.Value = num2str(H);
                % Prob dist plot   %
                plot(app.UIAxes,PMF,"g");
                bar(app.UIAxes,PMF);
                %Print%

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EnterthepathofatextfileEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthepathofatextfileEditFieldLabel.Text = 'Enter the path of a text file';
            app.fileSel = uieditfield(app.UIFigure, 'text');
            app.fileSel.ValueChangedFcn = createCallbackFcn(app, @fileSelValueChanged, true);
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.EntropyEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EntropyEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyEditField.ValueChangedFcn = createCallbackFcn(app, @EntropyEditFieldValueChanged, true);
            app.EntropyEditField.Editable = 'off';
            app.jcEntropyTable = uitable(app.UIFigure);
            app.jcEntropyTable.ColumnName = {'Joint Entropy'; 'Conditional Entropy (Calculated)'; 'Conditional Entropy (From Quick Rule)'};
            app.jcEntropyTable.RowName = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PMF'; 'Chars'; 'Number in graph'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RelativeEntropyEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeEntropyEditFieldLabel.Text = 'Relative Entropy';
            app.RelativeEntropyEditField = uieditfield(app.UIFigure, 'text');
            app.RelativeEntropyEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeEntropyEditFieldValueChanged, true);
            app.RelativeEntropyEditField.Editable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextinfileorwriteitEditFieldLabel = uilabel(app.UIFigure);
            app.TextinfileorwriteitEditFieldLabel.Text = 'Text in file or write it';
            app.TextinfileorwriteitEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proj1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end